classdef interpolation_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterpolationUIFigure      matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        Button                     matlab.ui.control.Button
        XEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.EditField
        YEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.EditField
        PointsEditFieldLabel       matlab.ui.control.Label
        PointsEditField            matlab.ui.control.EditField
        InterpolationLabel         matlab.ui.control.Label
        ChoosemethodDropDownLabel  matlab.ui.control.Label
        ChoosemethodDropDown       matlab.ui.control.DropDown
        Points_yEditFieldLabel     matlab.ui.control.Label
        Points_yEditField          matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
        switch app.ChoosemethodDropDown.Value
                case 'Polynomial Interpolation'
                    close all
            x=app.XEditField.Value
            y=app.YEditField.Value
            datax=str2num(x)
            datay=str2num(y)
            xm=str2num(app.PointsEditField.Value)
            x=datax;
            y=datay;
            n=size(datax);
n1=size(xm);
for k=1:n1(2)
    sum=0;
for i=1:n(2)
    prod=1;
    for j=1:n(2)
        if i~=j
        prod = prod.*((xm(k)-datax(j))/(datax(i)-datax(j)));
%         pdown=)*pdown
%         p=pup/pdown
        end 
    end
    sum=prod.*datay(i)+sum;
end
ym(k)=sum;
end
plot(app.UIAxes,datax,datay,'k-')
hold(app.UIAxes,"on");
plot(app.UIAxes,xm,ym,'ro');
ymm=sprintf('%f, ',ym)
app.Points_yEditField.Value=ymm


                case 'Linear Interpolation'
                    close all
                    valuex=app.XEditField.Value
                    valuey=app.YEditField.Value
                    dataX=str2num([valuex])
                    dataY=str2num([valuey])
                    x =str2num(app.PointsEditField.Value)
                    x1=0;
                    x2=0;
                    y1=0;
                    y2=0;
                    for i=1:length(dataX)-1
                    if dataX(i)<=x & dataX(i+1)>=x
                    x1=dataX(i);
                    x2=dataX(i+1);
                    y1=dataY(i);
                    y2=dataY(i+1);
                    end
                    end
                    y=(x-x2)*y1/(x1-x2)+(x-x1)*y2/(x2-x1);

                    plot(app.UIAxes,dataX,dataY,'k-')
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,x,y,'ro')
                    app.Points_yEditField.Value=sprintf('%f',y)
                    
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterpolationUIFigure = uifigure('Visible', 'off');
            app.InterpolationUIFigure.Name = 'Interpolation';
            app.UIAxes = uiaxes(app.InterpolationUIFigure);
            title(app.UIAxes, 'Interpolation Graph')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.InterpolationUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.XEditFieldLabel = uilabel(app.InterpolationUIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.InterpolationUIFigure, 'text');
            app.YEditFieldLabel = uilabel(app.InterpolationUIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.InterpolationUIFigure, 'text');
            app.PointsEditFieldLabel = uilabel(app.InterpolationUIFigure);
            app.PointsEditFieldLabel.Text = 'Points';
            app.PointsEditField = uieditfield(app.InterpolationUIFigure, 'text');
            app.InterpolationLabel = uilabel(app.InterpolationUIFigure);
            app.InterpolationLabel.Text = 'Interpolation';
            app.ChoosemethodDropDownLabel = uilabel(app.InterpolationUIFigure);
            app.ChoosemethodDropDownLabel.Text = 'Choose method';
            app.ChoosemethodDropDown = uidropdown(app.InterpolationUIFigure);
            app.ChoosemethodDropDown.Items = {'Linear Interpolation', 'Polynomial Interpolation', ''};
            app.ChoosemethodDropDown.Value = 'Polynomial Interpolation';
            app.Points_yEditFieldLabel = uilabel(app.InterpolationUIFigure);
            app.Points_yEditFieldLabel.Text = 'Points_y';
            app.Points_yEditField = uieditfield(app.InterpolationUIFigure, 'text');
            % Show the figure after all components are created
            app.InterpolationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interpolation_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterpolationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterpolationUIFigure)
        end
    end
end